classdef IAD_acquire < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IAD_UIFigure                    matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AcquisitionTab                  matlab.ui.container.Tab
        FullSequenceButton              matlab.ui.control.Button
        SphereSelectSwitch              matlab.ui.control.Switch
        SphereSelectSwitchLabel         matlab.ui.control.Label
        OpticalSwitchConnectButton      matlab.ui.control.Button
        SaturatedLampLabel              matlab.ui.control.Label
        IntegrationTimemsEditFieldLabel  matlab.ui.control.Label
        OutputNameEditFieldLabel        matlab.ui.control.Label
        SaturatedLamp                   matlab.ui.control.Lamp
        DarkLamp                        matlab.ui.control.Lamp
        ConnectedLamp                   matlab.ui.control.Lamp
        ConnectButton                   matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        OutputNameEditField             matlab.ui.control.EditField
        AcquireDataButton               matlab.ui.control.Button
        AutoexposeButton                matlab.ui.control.Button
        AcquireDarkButton               matlab.ui.control.Button
        InttimeEditField                matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        ProcessingTab                   matlab.ui.container.Tab
        StandardReflectanceLabel_5      matlab.ui.control.Label
        StandardReflectanceLabel_4      matlab.ui.control.Label
        StandardReflectanceLabel_3      matlab.ui.control.Label
        ProcessingPanel                 matlab.ui.container.Panel
        IncludeR0Switch                 matlab.ui.control.Switch
        IncludeR0Label                  matlab.ui.control.Label
        IncludeUnscatteredSwitch        matlab.ui.control.Switch
        IncludeUnscatteredLabel         matlab.ui.control.Label
        StandardReflectanceLabel_2      matlab.ui.control.Label
        SystemTypeButtonGroup           matlab.ui.container.ButtonGroup
        CustomButton                    matlab.ui.control.RadioButton
        DualButton                      matlab.ui.control.RadioButton
        SingleButton                    matlab.ui.control.RadioButton
        TStdListbox                     matlab.ui.control.ListBox
        RStdListbox                     matlab.ui.control.ListBox
        StandardReflectanceLabel        matlab.ui.control.Label
        TransmittanceLabel              matlab.ui.control.Label
        TMeasListbox                    matlab.ui.control.ListBox
        ReflectanceR_sLabel             matlab.ui.control.Label
        RMeasListbox                    matlab.ui.control.ListBox
        R0Listbox                       matlab.ui.control.ListBox
        UnscatListbox                   matlab.ui.control.ListBox
        UnscatRefListbox                matlab.ui.control.ListBox
        uipanel1                        matlab.ui.container.Panel
        StepSizeEditField               matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel          matlab.ui.control.Label
        SampleIndexofRefractionEditField  matlab.ui.control.NumericEditField
        SampleIndexofRefractionEditFieldLabel  matlab.ui.control.Label
        SampleThicknessmmEditField      matlab.ui.control.NumericEditField
        SampleThicknessmmEditFieldLabel  matlab.ui.control.Label
        AnisotropyEditField             matlab.ui.control.NumericEditField
        AnisotropyEditFieldLabel        matlab.ui.control.Label
        uipanel2                        matlab.ui.container.Panel
        SlidesPresentSwitch             matlab.ui.control.Switch
        SlidesPresentSwitchLabel        matlab.ui.control.Label
        SlideIndexofRefractionEditField  matlab.ui.control.NumericEditField
        SlideIndexofRefractionEditFieldLabel  matlab.ui.control.Label
        SlideThicknessmmEditField       matlab.ui.control.NumericEditField
        SlideThicknessmmEditFieldLabel  matlab.ui.control.Label
        SelectDirButton                 matlab.ui.control.Button
        DataDirectoryText               matlab.ui.control.Label
        SaveFileNameEditFieldLabel      matlab.ui.control.Label
        SaveFileNameEditField           matlab.ui.control.EditField
        ResetButton                     matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        MuspAxes                        matlab.ui.control.UIAxes
        MuaAxes                         matlab.ui.control.UIAxes
        RawAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        OldPath = ""

        % coefs = struct( ...
        %     'a0',3.20180e+02,'a1',8.50798e-1, ...
        %     'a2',-1.09490e-4,'a3',6.30802e-09 ...
        %     );                      % Calibration coefficients (CAC 230619)

        DataPtr     % Pointer to memory location for collected data

        LampOn = [0, 1, 0];         % Color to indicate ready status
        LampOff = [0.6, 0.6, 0.6];  % Color to indicate non-ready status

        IadInputFileName="temp.txt" % Overwrite this file to feed iad.exe
        IadInputHeader="SingleSphereParams" % Contains the header for iad.exe

        IadOutDir = "procdata"

        NoiseFloor (1,1) double = nan

    end
    
    properties (Access = public)

        Spec    spectrometer    % Object of "Spectrometer" class
        Switch  % Optical switch
        Shutter % Lamp shutter

        DarkData (:,1) double
        RawData (:,1) double

        Initialized (1,1) logical = false % Are we connected?
        HasDark  (1,1) logical = false % Has valid dark scan been taken?
        Px       (:,1) double   % Pixel indices. 0:Spec.NumPixels-1

        RawDir   (1,1) string   % Path to folder containing all data files
        Fnames   (:,1) string   % Filenames in DataDir wihout the ".txt"

        Wv       (:,1) double   % Vector of wavelengths in nm
        R_sample (:,3) double   % Reflectance spectrum
        T_sample (:,3) double   % Transmittance spectrum
        R_std    (:,3) double   % Calibration standard reflectance
        T_std    (:,3) double   % Transmittance reference reflectance
        R_0      (:,3) double   % Empty reflectance spectrum
        Unscat   (:,3) double   % Unscattered transmittance
        Unscat_R (:,3) double   % Unscattered reference

        Mr2      (:,1) double   % Corrected reflectance   
        Mt2      (:,1) double   % Corrected Transmittance
        Mu2      (:,1) double   % Corrected Unscattered Transmittance

    end
    
    methods (Access = private)

        % *****************************************************************
        % PRIVATE METHODS FOR ACQUISITION TAB
        % *****************************************************************
 
        function PlotData(app)

            if app.HasDark
                pdata = app.RawData-app.DarkData;
                plot(app.UIAxes, app.Spec.WvArray, pdata, 'g', ...
                    'LineWidth', 2)
                title(app.UIAxes, 'Dark-Corrected Spectrum')
            else
                pdata = app.RawData;
                plot(app.UIAxes, app.Spec.WvArray, app.RawData,...
                    'Linewidth', 2)
                title(app.UIAxes, 'Raw Spectrum')
            end
            app.UIAxes.YAxis.Exponent = 0;
            if max(pdata) >= 65500
                app.SaturatedLamp.Color = app.LampOn;
            else
                app.SaturatedLamp.Color = app.LampOff;
            end
            
        end

        function PlotDarkData(app)
            plot(app.UIAxes, app.Spec.WvArray, app.DarkData, ...
                "Color", [0.4, 0.4, 0.4], 'Linewidth', 2)
            app.UIAxes.YAxis.Exponent = 0;
            title(app.UIAxes, 'Dark Spectrum')
        end
        
        function EnableAll(app,state)

            [ ...
            app.FullSequenceButton.Enable, ...
            app.InttimeEditField.Enable, ...
            app.AutoexposeButton.Enable, ...
            app.AcquireDarkButton.Enable, ...
            app.AcquireDataButton.Enable, ...
            app.OutputNameEditField.Enable, ...
            app.SaveButton.Enable ...
            ] = deal(state);
            
        end

        
        function d = WaitForSpec(app)

            d = uiprogressdlg(app.IAD_UIFigure, "Message", "Acquiring", ...
                "Title", "Please Wait", "Value", 1);
         
        end

        % *****************************************************************
        % PRIVATE METHODS FOR PROCESSING TAB
        % *****************************************************************

        function PopulateListBoxes(app)

            app.sliceDirString;

            d = dir(fullfile(app.RawDir,"*.txt"));
            vals = string({d.name}').extractBefore('.txt');

            if ~isempty(vals)
                [app.RMeasListbox.Items, ...
                    app.TMeasListbox.Items, ...
                    app.RStdListbox.Items, ...
                    app.TStdListbox.Items, ...
                    app.R0Listbox.Items] = deal(vals);
            else
                [app.RMeasListbox.Items, ...
                    app.TMeasListbox.Items, ...
                    app.RStdListbox.Items, ...
                    app.TStdListbox.Items, ...
                    app.R0Listbox.Items] = deal("No Data Found.");
            end

            app.IncludeUnscatteredSwitchValueChanged

        end

        function GatherValues(app)

            % Assume, for now, that all files are correctly formatted.
            app.R_sample = load(fullfile(app.RawDir, ...
                app.RMeasListbox.Value + ".txt"));
            app.T_sample = load(fullfile(app.RawDir, ...
                app.TMeasListbox.Value + ".txt"));
            app.R_std = load(fullfile(app.RawDir, ...
                app.RStdListbox.Value + ".txt"));
            app.T_std = load(fullfile(app.RawDir, ...
                app.TStdListbox.Value + ".txt"));
            app.Wv = app.R_sample(:,2);
            
            % System correction method for single sphere measurements...
            if app.IncludeR0Switch.Value == "On"

                app.R_0 = load(fullfile(app.RawDir, ...
                    app.R0Listbox.Value + ".txt"));
                app.Mr2=medfilt1( ...
                    (app.R_sample(:,3)-app.R_0(:,3))./ ...
                    (app.R_std(:,3)-app.R_0(:,3)));
            else
                app.Mr2=medfilt1((app.R_sample(:,3))./(app.R_std(:,3)));
            end

            app.Mt2=medfilt1((app.T_sample(:,3))./(app.T_std(:,3)));

            % Unscattered Transmittance
            if app.IncludeUnscatteredSwitch.Value == "On"

                app.Unscat = load(fullfile(app.RawDir, ...
                    app.UnscatListbox.Value + ".txt"));
                app.Unscat_R = load(fullfile(app.RawDir, ...
                    app.UnscatRefListbox.Value + ".txt"));

                app.Mu2 = medfilt1(app.Unscat(:,3)./app.Unscat_R(:,3));

            end

        end

        
        function err = VerifyData(app)

            err = [];
            if isempty(app.RMeasListbox.Value) || ...
                    isempty(app.TMeasListbox.Value) || ...
                    isempty(app.RStdListbox.Value) || ...
                    isempty(app.TStdListbox.Value)
                msgbox("Please select R, T, and their references first.")
                err = [err, 1];
            end

            if app.IncludeR0Switch.Value == "On"
                if isempty(app.R0Listbox.Value) || ...
                        strcmp(app.R0Listbox.Value, "No data found.")
                    msgbox("Please select an R0 file or deselect the option.")
                    err = [err,2];
                end
                
            end
            if app.IncludeUnscatteredSwitch.Value == "On"
                if isempty(app.UnscatListbox.Value) || ...
                        strcmp(app.UnscatListbox.Value, "No data found.")
                    msgbox("No Unscattered transmittance selected.")
                    err = [err, 3];
                end
                if isempty(app.UnscatRefListbox.Value) || ...
                        strcmp(app.UnscatRefListbox.Value, "No data found.")
                    msgbox("No Unscattered reference selected.")
                    err = [err, 3];
                end
                
            end

        end        


        function WriteInputFile(app)

            % Adjust header values to match app
            outFileName= fullfile(app.IadOutDir, "temp.txt");

            % These replacements all rely on the specified order. As such,
            % empty lines must be skipped for the replacement to work.
            header = readlines(app.IadInputHeader,'EmptyLineRule','skip');
            header(2) = strrep(header(2), "1.33", ...
                num2str(app.SampleIndexofRefractionEditField.Value));
            header(4) = strrep(header(4), "2.00", ...
                num2str(app.SampleThicknessmmEditField.Value));
            
            if strcmp(app.SlidesPresentSwitch.Value,"No")
                header(3) = strrep(header(3),"1.56", "1.0");
                header(5) = strrep(header(5),"1.23", "0.0");
            else
                header(3) = strrep(header(3),"1.56", ...
                    num2str(app.SlideIndexofRefractionEditField.Value));
                header(5) = strrep(header(5),"1.23", ...
                    num2str(app.SlideThicknessmmEditField.Value));
            end
                           
            % Put the data in an array for readability
            stepsize = app.StepSizeEditField.Value;

            if app.IncludeUnscatteredSwitch.Value == "On"
                header(21) = "3";
                dataMat = [ ...
                    app.Wv(1:stepsize:end)'; ...
                    app.Mr2(1:stepsize:end)'; ...
                    app.Mt2(1:stepsize:end)'; ...
                    app.Mu2(1:stepsize:end)'];
            else
                header(21) = "2";
                dataMat = [ ...
                    app.Wv(1:stepsize:end)'; ...
                    app.Mr2(1:stepsize:end)'; ...
                    app.Mt2(1:stepsize:end)'];
            end

            % Write to file
            writelines(header, outFileName);
            fid = fopen(outFileName,'a');
            if app.IncludeUnscatteredSwitch.Value == "On"
                fprintf(fid,'%f  %f  %f  %f\n', dataMat);
            else
                fprintf(fid,'%f  %f  %f\n', dataMat);
            end
            fclose(fid);

        end        
        

        function PlotCorrectedData(app)

            hold(app.RawAxes,"off")
            if app.IncludeR0Switch.Value == "off"
                plot(app.RawAxes, app.Wv, app.Mr2, app.Wv, app.Mt2)
                hold(app.RawAxes,"on")
                plot(app.RawAxes, app.Wv, app.Mr2+app.Mt2,'k:')
                legend(app.RawAxes, "Reflectance", "Transmittance", ...
                    "R+T", "Location", "Best")
            else
                plot(app.RawAxes, app.Wv, app.Mr2, app.Wv, app.Mt2, ...
                    app.Wv, app.Mu2)
                hold(app.RawAxes,"on")
                plot(app.RawAxes, app.Wv, app.Mr2+app.Mt2+app.Mu2,'k:')
                legend(app.RawAxes, "Reflectance", "Transmittance", ...
                    "Unscattered", "R+T+U", "Location", "Best")
            end

            ylim(app.RawAxes, [0,1])
            xlabel(app.RawAxes, 'Wavelength (nm)')
            ylabel(app.RawAxes, 'Calibrated Reflectance/Transmittance')
            drawnow

        end


        function PlotOPs(app)

            fid = fopen(fullfile(app.IadOutDir, ...
                app.SaveFileNameEditField.Value+"_out.txt"),'r');
            for i=1:44  %skip first 44 lines in output file (header info)
                fgetl(fid);
            end
            
            % These file ending chars are a pain.
            data = nan(ceil(numel(app.Wv)/app.StepSizeEditField.Value),9);
            idx = 0;
            while ~feof(fid)
                idx = idx+1;
                data(idx,:) = double( ...
                    string(fgetl(fid)).strip('left'). ...
                    extractBefore('# *').split);
            end
            fclose(fid);

            outwv = data(:,1);
            mua = data(:,6);
            musp = data(:,7);

            plot(app.MuaAxes,outwv,mua)
            xlabel(app.MuaAxes, "Wavelength (nm)")
            ylabel(app.MuaAxes, "\mu_A (mm^-^1)")

            plot(app.MuspAxes,outwv,musp)
            xlabel(app.MuspAxes, "Wavelength (nm)")
            ylabel(app.MuspAxes, "\mu_S' (mm^-^1)")
            
        end        
        
        function sliceDirString(app)

            % Not sure if this is the best way to slice up the string...
            txtwid = app.DataDirectoryText.Position(3)/6;
            words = split(app.RawDir,filesep);
            splittext = strings(0);
            while ~isempty(words)
                lastidx = find(cumsum(strlength(words)+1) <= ...
                    txtwid,1,'last');
                splittext = [splittext; ...
                    words(1:lastidx).join(filesep) + filesep];
                words = words(lastidx+1:end);
            end

            app.DataDirectoryText.Text = splittext;            

        end


    end
    
    methods (Access = public)

        % *****************************************************************
        % PUBLIC METHODS FOR ACQUISITION TAB
        % *****************************************************************        
        
        function results = Acquire(app)
    
            d = app.WaitForSpec;
            app.Spec = ...
                app.Spec.setIntegrationTime(app.InttimeEditField.Value);    
            results = app.Spec.acquire;
            close(d)
            
        end
        
        function SetIntTime(app, inttime)           

            inttime = round(inttime);
            app.InttimeEditField.Value = inttime;
            app.InttimeEditFieldValueChanged;
       
        end

        
        function status = LoadSpec(app)
    
            snames = string({dir('./hardware').name}) ...
                .extractBefore('Spec.m');
            snames(ismissing(snames)) = [];

            [idx,tf] = listdlg('ListString',snames, ...
                'PromptString', 'Please Select a Spectrometer', ...
                 'SelectionMode','single','ListSize',[160,100]);
            waitfor(idx);

            % Need complete logic to handle no selection.
            % For now, status = 0
            if ~tf
                status = 0;
            else
                % oogly
                app.Spec = eval(snames(idx) + "Spec");
                app.ConnectButton.Enable = "on";
                app.InttimeEditField.Limits(1) = app.Spec.MinIntTime;
                status = 1;
            end

            %Bring app back to the front
            focus(app.IAD_UIFigure)

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            cd(fileparts(mfilename('fullpath')))
            app.RawDir = fullfile(pwd,'rawdata');
            addpath('./hardware/');

            % Acquisition tab:
            app.LoadSpec;

            app.ConnectedLamp.Color = app.LampOff;
            app.DarkLamp.Color = app.LampOff;

            % Processing tab:
            % Attempt to populate listboxes
            app.PopulateListBoxes;        

        end

        % Button pushed function: AcquireDataButton
        function AcquireDataButtonPushed(app, event)
           
            writeDigitalPin(app.Shutter,'D13',1);
            app.RawData = app.Acquire;
            app.PlotData;

        end

        % Value changed function: InttimeEditField
        function InttimeEditFieldValueChanged(app, event)
            
            app.DarkLamp.Color = app.LampOff;
            app.DarkData = zeros(size(app.Px));
            app.HasDark = false;
            
        end

        % Close request function: IAD_UIFigure
        function IAD_UIFigureCloseRequest(app, event)
            
            try
                app.Spec.closeSpec;
            catch
            end

            try 
                fclose(serial(app.Shutter.Port));
                clear a;
            catch
            end

            try 
                clear(app.Switch);
            catch
            end

            warning('off','MATLAB:rmpath:DirNotFound')
            rmpath("./hardware/")
            warning('on','MATLAB:rmpath:DirNotFound')

            delete(app)

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            [app.Spec, status] = app.Spec.initSpec;
            
            if status ~= 1        

                app.Initialized = false;
                app.ConnectedLamp.Color = app.LampOff;
                app.EnableAll("off");
                uialert(app.IAD_UIFigure, ...
                    "No spectrometer found.", '')

            else

                app.Px = (0:(app.Spec.NumPixels-1))';
                [app.DarkData, app.RawData] = deal(zeros(size(app.Px)));                
                app.UIAxes.XLim = ...
                    [min(app.Spec.WvArray), max(app.Spec.WvArray)];

                % Connect to other devices
                % omg I hate this code, please fix ASAP.
                app.Initialized = true;
                if isempty(app.Shutter)
                    try
                        app.Shutter = arduino;
                    catch
                        uialert(app.IAD_UIFigure, ...
                            "Please connect shutter Arduino and reconnect " + ...
                            "until this code can deal with no shutter.",'')
                        app.Initialized = false;
                    end
                end    
                
                if isempty(app.Switch)
                    app.OpticalSwitchConnectButtonPushed;  
                end

                if app.Initialized
                    app.EnableAll("on");
                    app.Initialized = true;
                    app.ConnectedLamp.Color = app.LampOn;
                else
                    app.EnableAll("off");
                    app.Initialized = false;
                    app.ConnectedLamp.Color = app.LampOff;
                end

            end

        end

        % Button pushed function: AutoexposeButton
        function AutoexposeButtonPushed(app, event)
            
            autoX = false;
            tries = 0;
            maxsig = 0;
            
            % Find noise floor real quick
            if isnan(app.NoiseFloor)
                temptime = app.InttimeEditField.Value;
                app.SetIntTime(app.Spec.MinIntTime);
                tmp = app.Acquire;
                app.NoiseFloor = mean(double(tmp)) + 3.*std(double(tmp));
                app.InttimeEditField.Value = temptime;
            end

            app.SetIntTime(app.InttimeEditField.Value);
            while ~autoX
            
                thisIntTime = app.InttimeEditField.Value;
                app.RawData = app.Acquire;
                oldmax = maxsig;
                    maxsig = max(app.RawData);
    
                if maxsig > 1.5*app.NoiseFloor && maxsig < 65000
                    newRatio = (63000-app.NoiseFloor)/(maxsig-app.NoiseFloor);
                    app.SetIntTime(min(65535, max(50, ...
                        round(newRatio*thisIntTime))));
                    app.RawData = app.Acquire;
                    autoX = true;
                        
                elseif maxsig <= 1.5*app.NoiseFloor
                    if thisIntTime <= 65535

                        % If signal strength isn't improving ...
                        if tries >= 2 && ...
                                (maxsig-app.NoiseFloor)/(oldmax-app.NoiseFloor) <= 2                            
                            uialert(app.IAD_UIFigure, ...
                                ["Weak signal detected."; ...
                                "Autoexpose failed."], "No Signal")
                            autoX = true;
                        else
                            % Multiply integration time by 10 (arbitrary)
                            app.SetIntTime(min(10*thisIntTime, 65535));
                            tries = tries+1;
                        end

                    end

                elseif maxsig >= 65000
                    if thisIntTime == app.Spec.MinIntTime
                        uialert(app.IAD_UIFigure, ...
                            "Saturation Detected", "Saturated");
                        autoX = true;

                    elseif maxsig == 65535
                        app.SetIntTime(max(50, round(0.3*thisIntTime)));

                    else
                        newRatio = (63000-app.Noisefloor)/(maxsig-app.Noisefloor);
                        app.SetIntTime(min(65535, max(50, ...
                            round(newRatio*thisIntTime))));

                    end                                                            

                end
             end
            app.PlotData;

        end

        % Button pushed function: AcquireDarkButton
        function AcquireDarkButtonPushed(app, event)
            
            writeDigitalPin(app.Shutter,'D13',0);
            app.DarkData = app.Acquire;
            app.DarkLamp.Color = app.LampOn;
            app.HasDark = true;
            app.PlotDarkData;

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            app.PlotData;

            if not(app.HasDark)
                uiconfirm(app.IAD_UI,'No valid dark data! Continue?', ...
                    'Zero Dark Thirty','Icon','warning')
            end

            fname = fullfile("rawdata", ...
                app.OutputNameEditField.Value + ".txt");

            d = dir(fname);
            if ~isempty(d)
                selection = uiconfirm(app.IAD_UIFigure, ...
                    'Overwrite Saved Data?', 'Confirm Overwrite',...
                    'Icon','warning');
                if strcmp(selection, "OK")
                    writematrix( ...
                        [app.Px, app.Spec.WvArray, ...
                        (app.RawData-app.DarkData)./ ...
                        (app.InttimeEditField.Value/1000)], ...
                        fname);
                    x = uiconfirm(app.IAD_UIFigure, "File Saved", "", ...
                        "Icon", "Success",'Options',"OK");
                else
                    x = uiconfirm(app.IAD_UIFigure, "File not saved.", "", ...
                        "Icon", "Warning",'Options',"OK");
                end
            else
                writematrix( ...
                    [app.Px,app.Spec.WvArray, ...
                    (app.RawData-app.DarkData)./ ...
                    (app.InttimeEditField.Value/1000)], ...
                    fname);
                x = uiconfirm(app.IAD_UIFigure, ...
                    "File Saved", "", "Icon", "Success",'Options',"OK");
            end


        end

        % Value changed function: SlidesPresentSwitch
        function SlidesPresentSwitchValueChanged(app, event)
            
            switch app.SlidesPresentSwitch.Value
                case ("No")
                    app.SlideIndexofRefractionEditField.Enable = "off";
                    app.SlideThicknessmmEditField.Enable = "off";
                case ("Yes")
                    app.SlideIndexofRefractionEditField.Enable = "on";
                    app.SlideThicknessmmEditField.Enable = "on";
            end  
            
        end

        % Selection changed function: SystemTypeButtonGroup
        function SystemTypeButtonGroupSelectionChanged(app, event)

            app.IadInputHeader = ...
                app.SystemTypeButtonGroup.SelectedObject.Tag + ".txt";
            
        end

        % Button pushed function: SelectDirButton
        function SelectDirButtonPushed(app, event)
            
            % Try to begin in "rawata" folder colocated with app. 
            app.RawDir = uigetdir(app.RawDir);
            
            app.PopulateListBoxes;
            %Bring app back to the front
            focus(app.IAD_UIFigure)

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            err = app.VerifyData;
            if isempty(err)
                app.GatherValues;
                app.PlotCorrectedData;
                app.WriteInputFile;
            
                commandwindow

                % Orange text hack from Yair Altman/Carsten Allefeld
                fprintf('\nUsing Parameters from: [\b%s]\b', ...
                    app.IadInputHeader);
                fprintf('\nCalculating OPs for [\b%g]\b wavelengths.\n', ...
                    numel(app.Wv(1:app.StepSizeEditField.Value:end)));

                cmd = "iad -g " + app.AnisotropyEditField.Value + ...
                    " -o " + fullfile(app.IadOutDir, ...
                    app.SaveFileNameEditField.Value+"_out.txt") + ...
                    fullfile(" " + app.IadOutDir, app.IadInputFileName);

                if ispc
                    system(cmd);
                    app.PlotOPs;
                else
                    error("*ix systems not tested yet")
                end

                focus(app.IAD_UIFigure)

            else
                error("Unspecified errors found in VerifyData(). Good luck!")
                
            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            % Clear DataDir
            app.DataDirectoryText.Text = "";
            % Clear list boxes
            [app.RMeasListbox.Items, ...
                app.TMeasListbox.Items, ...
                app.RStdListbox.Items, ...
                app.TStdListbox.Items, ...
                app.R0Listbox.Items, ...
                app.UnscatListbox.Items] = deal("");

            % Reset input parameters
            app.AnisotropyEditField.Value = 0.7;
            app.SampleThicknessmmEditField.Value = 2;
            app.SampleIndexofRefractionEditField.Value = 1.33;
            app.StepSizeEditField.Value = 1;

            % Slide Value Panel
            app.SlideThicknessmmEditField.Value = 1.23;
            app.SlideIndexofRefractionEditField.Value = 1.56;
            app.SlidesPresentSwitch.Value = "No";
            app.SlidesPresentSwitchValueChanged;

            % Processing Panel
            app.IncludeR0Switch.Value = "Off";
            app.IncludeR0SwitchValueChanged;
            app.IncludeUnscatteredSwitch.Value = "Off";
            app.IncludeUnscatteredSwitchValueChanged;

        end

        % Button down function: ProcessingTab
        function ProcessingTabButtonDown(app, event)
            app.PopulateListBoxes
        end

        % Button pushed function: FullSequenceButton
        function FullSequenceButtonPushed(app, event)
            
            pat = "_" + wildcardPattern("Except","_") + textBoundary;
            basename = extractBefore(app.OutputNameEditField.Value,pat);

            dummy = uiconfirm(app.IAD_UIFigure, ...
                ["Remove Port Cover"; "Position sphere near source"], ...
                "Measurement: R0","Icon","info",'Options',"OK");
            app.OutputNameEditField.Value = basename + "_R0";
            app.InttimeEditField.Value = 60000;
            app.AcquireDarkButtonPushed;
            app.AcquireDataButtonPushed;
            app.SaveButtonPushed;

            pause(1)

            dummy = uiconfirm(app.IAD_UIFigure, ...
                ["Replace Port Cover"; "Position sphere away from source"], ...
                "Measurement: T0","Icon","info",'Options',"OK");
            app.OutputNameEditField.Value = basename + "_T0";
            writeDigitalPin(app.Shutter,'D13',1);                        
            app.InttimeEditField.Value = 5000;
            app.AutoexposeButtonPushed;
            app.AcquireDataButtonPushed;
            app.AcquireDarkButtonPushed;            
            app.SaveButtonPushed;

            pause(1)

            dummy = uiconfirm(app.IAD_UIFigure, ...
                ["Place sample on near port."; "Position sphere away from source"], ...
                "Measurement: T_sample","Icon","info",'Options',"OK");
            app.OutputNameEditField.Value = basename + "_Tsample";
            writeDigitalPin(app.Shutter,'D13',1);  
            app.InttimeEditField.Value = 5000;
            app.AutoexposeButtonPushed;                      
            app.AcquireDataButtonPushed;
            app.AcquireDarkButtonPushed;            
            app.SaveButtonPushed;

            pause(1)

            dummy = uiconfirm(app.IAD_UIFigure, ...
                ["No sample, port cover installed."; "Position sphere near source."], ...
                "Measurement: R_sample","Icon","info",'Options',"OK");
            app.OutputNameEditField.Value = basename + "_Rstd";
            writeDigitalPin(app.Shutter,'D13',1);
            app.InttimeEditField.Value = 5000;
            app.AutoexposeButtonPushed;                        
            app.AcquireDataButtonPushed;
            app.AcquireDarkButtonPushed;            
            app.SaveButtonPushed;

            pause(1)

            dummy = uiconfirm(app.IAD_UIFigure, ...
                ["Place sample on far port."; "Position sphere near source"], ...
                "Measurement: R_sample","Icon","info",'Options',"OK");
            app.OutputNameEditField.Value = basename + "_Rsample";
            writeDigitalPin(app.Shutter,'D13',1); 
            app.InttimeEditField.Value = 5000;
            app.AutoexposeButtonPushed;                       
            app.AcquireDataButtonPushed;
            app.AcquireDarkButtonPushed;            
            app.SaveButtonPushed;

            f = msgbox("Acquiring unscattered transmittance.");
            pause(1)
            close(f);

            app.OutputNameEditField.Value = basename + "_unscat";
            app.InttimeEditField.Value = 10000;
            write(app.Switch,[1,18,0,2],"uint8")
            writeDigitalPin(app.Shutter,'D13',1);
            app.AutoexposeButtonPushed;
            app.AcquireDataButtonPushed;
            writeDigitalPin(app.Shutter,'D13',0);            
            app.AcquireDarkButtonPushed;
            app.SaveButtonPushed;

            dummy = uiconfirm(app.IAD_UIFigure, ...
                ["Remove sample."], ...
                "Measurement: Unscattered","Icon","info",'Options',"OK");

            app.OutputNameEditField.Value = basename + "_unscatRef";
            app.InttimeEditField.Value = 10000;
            writeDigitalPin(app.Shutter,'D13',1);
            app.AutoexposeButtonPushed; 
            app.AcquireDataButtonPushed;
            writeDigitalPin(app.Shutter,'D13',0);            
            app.AcquireDarkButtonPushed;
            app.SaveButtonPushed;
            write(app.Switch,[1,18,0,1],"uint8");

            uiconfirm(app.IAD_UIFigure,...
                ["Status: Complete"],...
                "Done.", "Icon","info","Options","OK");

        end

        % Value changed function: SphereSelectSwitch
        function SphereSelectSwitchValueChanged(app, event)
           
            val = [1,18,0, str2double(app.SphereSelectSwitch.Value)];
            write(app.Switch, val, "uint8");
            
        end

        % Button pushed function: OpticalSwitchConnectButton
        function OpticalSwitchConnectButtonPushed(app, event)
            if ~isempty(app.Switch)
                uialert(app.IAD_UIFigure, "Already connected...I think.",'')
            else
                spl = serialportlist;
                if numel(spl) ~= 2
                    uialert(app.IAD_UIFigure, ...
                        ['Wrong number of serial devices connected. Please ', ...
                        'connect only spectrometer, arduino, and optical switch ',...
                        'until this code is better. Then reconnect.'],'')
                    app.Initialized = false;
                else
                    notThisOne = find(contains(spl,app.Shutter.Port));
                    app.Switch = serialport(spl(end-notThisOne+1),9600,"Timeout",5);
                    
                    app.SphereSelectSwitch.Enable = "on";
        
                    % Select main sphere (FIX when fixing logic)
                    app.SphereSelectSwitch.Value = '1';
                    app.SphereSelectSwitchValueChanged
                end
                % app.OpticalSwitchConnectButton.Enable = "off";
            end
        end

        % Value changed function: IncludeUnscatteredSwitch
        function IncludeUnscatteredSwitchValueChanged(app, event)
            
            switch app.IncludeUnscatteredSwitch.Value
                case "Off"

                    app.UnscatListbox.Enable = "off";
                    app.UnscatListbox.Items = "";
                    app.UnscatRefListbox.Enable = "off";
                    app.UnscatRefListbox.Items = "";

                case "On"

                    d = dir(fullfile(app.RawDir,"*.txt"));
                    vals = string({d.name}').extractBefore('.txt');
                    vals = vals(contains(vals,"unscat"));
                    if ~isempty(vals)
                        app.UnscatListbox.Items = vals;
                        app.UnscatRefListbox.Items = vals;
                    else
                        app.UnscatListbox.Items = "No data found.";
                        app.UnscatRefListbox.Items = "No data found.";
                    end

                    app.UnscatListbox.Enable = "on";
                    app.UnscatRefListbox.Enable = "on";

            end
            
        end

        % Value changed function: IncludeR0Switch
        function IncludeR0SwitchValueChanged(app, event)
            
            switch app.IncludeR0Switch.Value
                case "Off"
                    app.R0Listbox.Enable = "off";
                case "On"
                    app.R0Listbox.Enable = "on";
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IAD_UIFigure = uifigure('Visible', 'off');
            app.IAD_UIFigure.Name = 'MATLAB App';
            app.IAD_UIFigure.CloseRequestFcn = createCallbackFcn(app, @IAD_UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.IAD_UIFigure);
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.UIAxes = uiaxes(app.AcquisitionTab);
            title(app.UIAxes, 'No Data')
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Counts')
            app.UIAxes.YLim = [-10 65535];
            app.InttimeEditField = uieditfield(app.AcquisitionTab, 'numeric');
            app.InttimeEditField.Limits = [50 65535];
            app.InttimeEditField.RoundFractionalValues = 'on';
            app.InttimeEditField.ValueDisplayFormat = '%.8g';
            app.InttimeEditField.ValueChangedFcn = createCallbackFcn(app, @InttimeEditFieldValueChanged, true);
            app.InttimeEditField.Enable = 'off';
            app.InttimeEditField.Value = 50;
            app.AcquireDarkButton = uibutton(app.AcquisitionTab, 'push');
            app.AcquireDarkButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireDarkButtonPushed, true);
            app.AcquireDarkButton.Enable = 'off';
            app.AcquireDarkButton.Text = 'Acquire Dark';
            app.AutoexposeButton = uibutton(app.AcquisitionTab, 'push');
            app.AutoexposeButton.ButtonPushedFcn = createCallbackFcn(app, @AutoexposeButtonPushed, true);
            app.AutoexposeButton.Enable = 'off';
            app.AutoexposeButton.Text = 'Autoexpose';
            app.AcquireDataButton = uibutton(app.AcquisitionTab, 'push');
            app.AcquireDataButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireDataButtonPushed, true);
            app.AcquireDataButton.Enable = 'off';
            app.AcquireDataButton.Text = 'Acquire Data';
            app.OutputNameEditField = uieditfield(app.AcquisitionTab, 'text');
            app.OutputNameEditField.Enable = 'off';
            app.OutputNameEditField.Value = 'Intralipid_T';
            app.SaveButton = uibutton(app.AcquisitionTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.ConnectButton = uibutton(app.AcquisitionTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = {'CONNECT'; 'TO'; 'SPECTROMETER'};
            app.ConnectedLamp = uilamp(app.AcquisitionTab);
            app.DarkLamp = uilamp(app.AcquisitionTab);
            app.SaturatedLamp = uilamp(app.AcquisitionTab);
            app.OutputNameEditFieldLabel = uilabel(app.AcquisitionTab);
            app.OutputNameEditFieldLabel.Text = 'Output Name';
            app.IntegrationTimemsEditFieldLabel = uilabel(app.AcquisitionTab);
            app.IntegrationTimemsEditFieldLabel.Text = 'Integration Time (ms)';
            app.SaturatedLampLabel = uilabel(app.AcquisitionTab);
            app.SaturatedLampLabel.Text = 'Saturated';
            app.OpticalSwitchConnectButton = uibutton(app.AcquisitionTab, 'push');
            app.OpticalSwitchConnectButton.ButtonPushedFcn = createCallbackFcn(app, @OpticalSwitchConnectButtonPushed, true);
            app.OpticalSwitchConnectButton.Enable = 'off';
            app.OpticalSwitchConnectButton.Visible = 'off';
            app.OpticalSwitchConnectButton.Text = 'Optical Switch Connect';
            app.SphereSelectSwitchLabel = uilabel(app.AcquisitionTab);
            app.SphereSelectSwitchLabel.Enable = 'off';
            app.SphereSelectSwitchLabel.Text = 'Sphere Select';
            app.SphereSelectSwitch = uiswitch(app.AcquisitionTab, 'slider');
            app.SphereSelectSwitch.Items = {'1', '2'};
            app.SphereSelectSwitch.ValueChangedFcn = createCallbackFcn(app, @SphereSelectSwitchValueChanged, true);
            app.SphereSelectSwitch.Enable = 'off';
            app.SphereSelectSwitch.Value = '1';
            app.FullSequenceButton = uibutton(app.AcquisitionTab, 'push');
            app.FullSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @FullSequenceButtonPushed, true);
            app.FullSequenceButton.Enable = 'off';
            app.FullSequenceButton.Text = 'Full Sequence';
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.ProcessingTab.ButtonDownFcn = createCallbackFcn(app, @ProcessingTabButtonDown, true);
            app.RawAxes = uiaxes(app.ProcessingTab);
            xlabel(app.RawAxes, 'Wavelength (nm)')
            ylabel(app.RawAxes, 'Calibrated Reflectance/Transmittance')
            app.RawAxes.Tag = 'axes1';
            app.MuaAxes = uiaxes(app.ProcessingTab);
            xlabel(app.MuaAxes, 'Wavelength (nm)')
            ylabel(app.MuaAxes, '\mu_A (mm^-^1)')
            app.MuaAxes.XLim = [400 1100];
            app.MuaAxes.YLim = [0 0.1];
            app.MuaAxes.Tag = 'axes2';
            app.MuspAxes = uiaxes(app.ProcessingTab);
            xlabel(app.MuspAxes, 'Wavelength (nm)')
            ylabel(app.MuspAxes, '\mu_S'' (mm^-^1)')
            app.MuspAxes.Tag = 'axes3';
            app.CalculateButton = uibutton(app.ProcessingTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Tag = 'Calculate';
            app.CalculateButton.Text = 'Calculate';
            app.ResetButton = uibutton(app.ProcessingTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tag = 'reset';
            app.ResetButton.Text = 'Reset';
            app.SaveFileNameEditField = uieditfield(app.ProcessingTab, 'text');
            app.SaveFileNameEditField.Value = 'test';
            app.SaveFileNameEditFieldLabel = uilabel(app.ProcessingTab);
            app.SaveFileNameEditFieldLabel.Text = 'Save File Name';
            app.DataDirectoryText = uilabel(app.ProcessingTab);
            app.DataDirectoryText.Tag = 'text9';
            app.DataDirectoryText.Text = '';
            app.SelectDirButton = uibutton(app.ProcessingTab, 'push');
            app.SelectDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirButtonPushed, true);
            app.SelectDirButton.Tag = 'pushbutton3';
            app.SelectDirButton.Text = 'Select Raw Data Directory';
            app.uipanel2 = uipanel(app.ProcessingTab);
            app.uipanel2.Title = 'Slide/Cuvette Parameters';
            app.uipanel2.Tag = 'uipanel2';
            app.SlideThicknessmmEditFieldLabel = uilabel(app.uipanel2);
            app.SlideThicknessmmEditFieldLabel.Text = 'Slide Thickness (mm)';
            app.SlideThicknessmmEditField = uieditfield(app.uipanel2, 'numeric');
            app.SlideThicknessmmEditField.Limits = [0 100];
            app.SlideThicknessmmEditField.Value = 1.23;
            app.SlideIndexofRefractionEditFieldLabel = uilabel(app.uipanel2);
            app.SlideIndexofRefractionEditFieldLabel.Text = 'Slide Index of Refraction';
            app.SlideIndexofRefractionEditField = uieditfield(app.uipanel2, 'numeric');
            app.SlideIndexofRefractionEditField.Value = 1.56;
            app.SlidesPresentSwitchLabel = uilabel(app.uipanel2);
            app.SlidesPresentSwitchLabel.Text = 'Slides Present?';
            app.SlidesPresentSwitch = uiswitch(app.uipanel2, 'slider');
            app.SlidesPresentSwitch.Items = {'No', 'Yes'};
            app.SlidesPresentSwitch.ValueChangedFcn = createCallbackFcn(app, @SlidesPresentSwitchValueChanged, true);
            app.SlidesPresentSwitch.Value = 'Yes';
            app.uipanel1 = uipanel(app.ProcessingTab);
            app.uipanel1.Title = 'Input Parameters';
            app.uipanel1.Tag = 'uipanel1';
            app.AnisotropyEditFieldLabel = uilabel(app.uipanel1);
            app.AnisotropyEditFieldLabel.Text = 'Anisotropy';
            app.AnisotropyEditField = uieditfield(app.uipanel1, 'numeric');
            app.AnisotropyEditField.Limits = [-1 1];
            app.AnisotropyEditField.Value = 0.7;
            app.SampleThicknessmmEditFieldLabel = uilabel(app.uipanel1);
            app.SampleThicknessmmEditFieldLabel.Text = 'Sample Thickness (mm)';
            app.SampleThicknessmmEditField = uieditfield(app.uipanel1, 'numeric');
            app.SampleThicknessmmEditField.Limits = [0 100];
            app.SampleThicknessmmEditField.Value = 2;
            app.SampleIndexofRefractionEditFieldLabel = uilabel(app.uipanel1);
            app.SampleIndexofRefractionEditFieldLabel.Text = 'Sample Index of Refraction';
            app.SampleIndexofRefractionEditField = uieditfield(app.uipanel1, 'numeric');
            app.SampleIndexofRefractionEditField.Limits = [0 Inf];
            app.SampleIndexofRefractionEditField.Value = 1.33;
            app.StepSizeEditFieldLabel = uilabel(app.uipanel1);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.uipanel1, 'numeric');
            app.StepSizeEditField.Limits = [1 Inf];
            app.StepSizeEditField.RoundFractionalValues = 'on';
            app.StepSizeEditField.ValueDisplayFormat = '%.u';
            app.StepSizeEditField.Value = 1;
            app.UnscatRefListbox = uilistbox(app.ProcessingTab);
            app.UnscatRefListbox.Items = {};
            app.UnscatRefListbox.Enable = 'off';
            app.UnscatRefListbox.Value = {};
            app.UnscatListbox = uilistbox(app.ProcessingTab);
            app.UnscatListbox.Items = {};
            app.UnscatListbox.Enable = 'off';
            app.UnscatListbox.Value = {};
            app.R0Listbox = uilistbox(app.ProcessingTab);
            app.R0Listbox.Items = {};
            app.R0Listbox.Enable = 'off';
            app.R0Listbox.Value = {};
            app.RMeasListbox = uilistbox(app.ProcessingTab);
            app.RMeasListbox.Items = {};
            app.RMeasListbox.Value = {};
            app.ReflectanceR_sLabel = uilabel(app.ProcessingTab);
            app.ReflectanceR_sLabel.Text = 'Reflectance (R_s)';
            app.TMeasListbox = uilistbox(app.ProcessingTab);
            app.TMeasListbox.Items = {};
            app.TMeasListbox.Value = {};
            app.TransmittanceLabel = uilabel(app.ProcessingTab);
            app.TransmittanceLabel.Text = 'Transmittance';
            app.StandardReflectanceLabel = uilabel(app.ProcessingTab);
            app.StandardReflectanceLabel.Text = 'Reflectance Reference (R_std)';
            app.RStdListbox = uilistbox(app.ProcessingTab);
            app.RStdListbox.Items = {};
            app.RStdListbox.Value = {};
            app.TStdListbox = uilistbox(app.ProcessingTab);
            app.TStdListbox.Items = {};
            app.TStdListbox.Value = {};
            app.SystemTypeButtonGroup = uibuttongroup(app.ProcessingTab);
            app.SystemTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SystemTypeButtonGroupSelectionChanged, true);
            app.SystemTypeButtonGroup.Title = 'System Type';
            app.SingleButton = uiradiobutton(app.SystemTypeButtonGroup);
            app.SingleButton.Tag = 'SingleSphereParams';
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.DualButton = uiradiobutton(app.SystemTypeButtonGroup);
            app.DualButton.Tag = 'DualSphereParams';
            app.DualButton.Text = 'Dual';
            app.CustomButton = uiradiobutton(app.SystemTypeButtonGroup);
            app.CustomButton.Tag = 'CustomSphereParams';
            app.CustomButton.Text = 'Custom';
            app.StandardReflectanceLabel_2 = uilabel(app.ProcessingTab);
            app.StandardReflectanceLabel_2.Text = 'Transmittance Reference';
            app.ProcessingPanel = uipanel(app.ProcessingTab);
            app.ProcessingPanel.Title = 'Processing';
            app.IncludeUnscatteredLabel = uilabel(app.ProcessingPanel);
            app.IncludeUnscatteredLabel.Text = {'Include'; 'Unscattered?'};
            app.IncludeUnscatteredSwitch = uiswitch(app.ProcessingPanel, 'slider');
            app.IncludeUnscatteredSwitch.ValueChangedFcn = createCallbackFcn(app, @IncludeUnscatteredSwitchValueChanged, true);
            app.IncludeR0Label = uilabel(app.ProcessingPanel);
            app.IncludeR0Label.Text = 'Include R0?';
            app.IncludeR0Switch = uiswitch(app.ProcessingPanel, 'slider');
            app.IncludeR0Switch.ValueChangedFcn = createCallbackFcn(app, @IncludeR0SwitchValueChanged, true);
            app.StandardReflectanceLabel_3 = uilabel(app.ProcessingTab);
            app.StandardReflectanceLabel_3.Enable = 'off';
            app.StandardReflectanceLabel_3.Text = 'Reflectance Empty (R_0)';
            app.StandardReflectanceLabel_4 = uilabel(app.ProcessingTab);
            app.StandardReflectanceLabel_4.Enable = 'off';
            app.StandardReflectanceLabel_4.Text = 'Unscat';
            app.StandardReflectanceLabel_5 = uilabel(app.ProcessingTab);
            app.StandardReflectanceLabel_5.Enable = 'off';
            app.StandardReflectanceLabel_5.Text = 'Unscat Ref';
            % Show the figure after all components are created
            app.IAD_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IAD_acquire

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IAD_UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IAD_UIFigure)
        end
    end
end